classdef VNA_control_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RightPanel                     matlab.ui.container.Panel
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        RemainingEditField             matlab.ui.control.EditField
        RemainingEditFieldLabel        matlab.ui.control.Label
        ElapsedEditField               matlab.ui.control.EditField
        ElapsedEditFieldLabel          matlab.ui.control.Label
        CollectedFilesListBox          matlab.ui.control.ListBox
        CollectedFilesListBoxLabel     matlab.ui.control.Label
        CurrentFilenameEditField       matlab.ui.control.EditField
        CurrentFilenameEditFieldLabel  matlab.ui.control.Label
        SavingDataLamp                 matlab.ui.control.Lamp
        SavingDataLampLabel            matlab.ui.control.Label
        SweepingLamp                   matlab.ui.control.Lamp
        SweepingLampLabel              matlab.ui.control.Label
        progress_FG                    matlab.ui.control.Label
        progress_BG                    matlab.ui.control.Label
        percentLabel                   matlab.ui.control.Label
        ProgressLabel                  matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        StateEditFieldLabel            matlab.ui.control.Label
        sParamAxes                     matlab.ui.control.UIAxes
        dataCollectAxes                matlab.ui.control.UIAxes
        LeftPanel                      matlab.ui.container.Panel
        WindowMHzEditField             matlab.ui.control.NumericEditField
        WindowMHzLabel                 matlab.ui.control.Label
        SplitSweepCheckBox             matlab.ui.control.CheckBox
        PowerdBmEditField              matlab.ui.control.NumericEditField
        PowerdBmEditFieldLabel         matlab.ui.control.Label
        RuntimehrEditField             matlab.ui.control.NumericEditField
        RuntimehrEditFieldLabel        matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        MessageTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel           matlab.ui.control.Label
        PointsEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel           matlab.ui.control.Label
        RBWHzEditField                 matlab.ui.control.NumericEditField
        RBWHzEditFieldLabel            matlab.ui.control.Label
        SweepStopMHzEditField          matlab.ui.control.NumericEditField
        SweepStopMHzEditFieldLabel     matlab.ui.control.Label
        SweepStartMHzEditField         matlab.ui.control.NumericEditField
        SweepStartMHzEditFieldLabel    matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % R&S ZNB20
        visausb % Description
        sParamLine   % line handle
        fileSizeLine % line handle
        %appTimer   % timer object (countdown)
        timeRemaining % seconds
        timeElapsed
        progress % percentage
        fsmState string = "IDLE"
        runStartTime datetime
        runDuration_s double = 0
        sweepIdx double = 1
        fsmTimer timer
        sweepStartTime datetime
        saveFolder string = "../VNA_data"

        fileSizeX
        fileSizeY
        

        isStopped logical = true % Description
        percent 
        
        overallStartHz double = NaN
        overallStopHz double = NaN
        windowHz double = 10e6
        currentStartHz double = NaN
        currentStopHz double = NaN
        splitSweep logical = false
    end
    
    
    methods (Access = private)
        
        % function countdownCallback(app)
        %     disp('tick');
        %     if app.timeRemaining > 0
        %         app.timeRemaining = app.timeRemaining - 1;
        % 
        %         % elapsed
        %         app.timeElapsed = app.timeElapsed + 1;
        % 
        %         % format remaining
        %         hours   = floor( app.timeRemaining / 3600);
        %         minutes = floor(mod( app.timeRemaining, 3600) / 60);
        %         seconds = mod( app.timeRemaining, 60);
        %         app.RemainingEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);
        % 
        %         % format elapsed
        %         hours   = floor( app.timeElapsed / 3600);
        %         minutes = floor(mod( app.timeElapsed, 3600) / 60);
        %         seconds = mod( app.timeElapsed, 60);
        %         app.ElapsedEditField.Value = sprintf('%02d:%02d:%02d', hours, minutes, seconds);
        % 
        %         % update progress bar + label
        %         app.progress = round(app.timeElapsed / (3600*app.RuntimehrEditField.Value),2);
        %         app.percentLabel.Text = strcat(num2str(1e2*app.progress),'%');
        %         app.progress_FG.Position(3) = max(10,max(0,floor(app.progress*app.progress_BG.Position(3))-6));
        %     else
        %         stop(app.appTimer);
        %         delete(app.appTimer);
        %     end
        % 
        % end
        function stopRun(app)
            % Safe stop that does NOT require an event argument
            app.fsmState = "STOPPED";
            app.StateEditField.Value = "STOPPED";
            app.isStopped = true;
            if app.splitSweep
                totalWindows = ceil((app.overallStopHz - app.overallStartHz) / app.windowHz);
                % windowsDone = min(app.sweepIdx - 1, totalWindows);
                windowsDone = min(app.sweepIdx, totalWindows);
                disp(windowsDone);
                windowsLeft = max(0, totalWindows - windowsDone);
                                disp(windowsLeft)

                app.ElapsedEditField.Value = char(duration(0,0,floor(app.timeElapsed),'Format','hh:mm:ss'));
                app.RemainingEditField.Value = sprintf('%d windows', windowsLeft);
            end
            app.percent = 100;
            app.percentLabel.Text = sprintf('%d%%', app.percent);
            
            % stop FSM timer
            if ~isempty(app.fsmTimer) && isvalid(app.fsmTimer)
                stop(app.fsmTimer);
                delete(app.fsmTimer);
            end
            % (optional) stop countdown timer too
            % if ~isempty(app.appTimer) && isvalid(app.appTimer)
            %     stop(app.appTimer);
            %     delete(app.appTimer);
            % end
        
            % lamps
            app.SweepingLamp.Color  = 'r';
            app.SavingDataLamp.Color = 'r';
        end
        
        function fsmTick(app)
            try
                disp("----- FSM TICK -----");
                disp("State = " + app.fsmState);
                disp("splitSweep = " + string(app.splitSweep));
                disp("timeElapsed = " + string(app.timeElapsed));
                disp("runDuration_s = " + string(app.runDuration_s));
                disp("sweepIdx = " + string(app.sweepIdx));
                disp("currentStartHz = " + string(app.currentStartHz));
                disp("currentStopHz = " + string(app.currentStopHz));
                % ---- stop condition: runtime elapsed ----
                app.timeElapsed = seconds(datetime("now") - app.runStartTime);
        
                % update UI runtime fields
                app.ElapsedEditField.Value = char(duration(0,0,floor(app.timeElapsed),'Format','hh:mm:ss'));
                % remaining = max(0, app.runDuration_s - app.timeElapsed);
                % app.RemainingEditField.Value = char(duration(0,0,floor(remaining),'Format','hh:mm:ss'));
                if app.splitSweep
                    totalWindows = ceil((app.overallStopHz - app.overallStartHz) / app.windowHz);
                    % windowsDone = min(app.sweepIdx - 1, totalWindows);
                    windowsDone =  min(app.sweepIdx-1, totalWindows);
                    windowsLeft = max(0, totalWindows - windowsDone);

                    app.ElapsedEditField.Value = char(duration(0,0,floor(app.timeElapsed),'Format','hh:mm:ss'));
                    app.RemainingEditField.Value = sprintf('%d windows', windowsLeft);
                else
                    app.ElapsedEditField.Value = char(duration(0,0,floor(app.timeElapsed),'Format','hh:mm:ss'));
                    remaining = max(0, app.runDuration_s - app.timeElapsed);
                    app.RemainingEditField.Value = char(duration(0,0,floor(remaining),'Format','hh:mm:ss'));
                end
                app.StateEditField.Value = char(app.fsmState);


                if app.splitSweep
                    totalWindows = ceil((app.overallStopHz - app.overallStartHz) / app.windowHz);
                    if totalWindows <= 0
                        rawPercent = 0;
                    else
                        rawPercent = 100 * ((app.sweepIdx ) / totalWindows);
                    end
                else
                    if app.runDuration_s > 0
                        rawPercent = 100 * (app.timeElapsed / app.runDuration_s);
                    else
                        rawPercent = 0;
                    end
                end
                
                if app.isStopped
                    app.percent = 100;
                else
                    app.percent = min(99, floor(rawPercent));
                end
          
                % app.percentLabel.iText = string(round(100*app.progress)) + "%";

                app.percentLabel.Text = sprintf('%d%%', app.percent);
                
                % Update progress bar
                bgWidth = app.progress_BG.Position(3);
                app.progress_FG.Position(3) = max(10, floor((app.percent/100) * bgWidth)-6);
        
                switch app.fsmState
        
                    case "START_SWEEP"
                        app.timeElapsed = seconds(datetime("now") - app.runStartTime);
                        if ~app.splitSweep && app.timeElapsed >= app.runDuration_s
                            app.stopRun();
                            disp("Runtime finished (not starting another sweep).");
                            return;
                        end
                     
                        app.SweepingLamp.Color = 'g';
        
                        % apply sweep settings (channel 2)
                        % fStart_Hz = app.SweepStartMHzEditField.Value * 1e6;
                        % fStop_Hz  = app.SweepStopMHzEditField.Value  * 1e6;
                        disp("[START_SWEEP] checking stop conditions");
                        if app.splitSweep && app.currentStartHz >= app.overallStopHz
                            app.stopRun();
                            disp("Finished all sweep windows.");
                            return;
                        end
                        
                        fStart_Hz = app.currentStartHz;
                        fStop_Hz  = app.currentStopHz;
                        app.sweepIdx = app.sweepIdx + 1;

                        if app.splitSweep
                            app.MessageTextArea.Value = { ...
                                sprintf('Sweep %d', app.sweepIdx); ...
                                sprintf('Window start: %.6f MHz', fStart_Hz/1e6); ...
                                sprintf('Window stop: %.6f MHz', fStop_Hz/1e6); ...
                                sprintf('Window size: %.6f MHz', app.windowHz/1e6)}
                        else
                            app.MessageTextArea.Value = { ...
                                sprintf('Sweep %d', app.sweepIdx); ...
                                sprintf('Start: %.6f MHz', fStart_Hz/1e6); ...
                                sprintf('Stop: %.6f MHz', fStop_Hz/1e6)};
                        end

                        nPts      = max(1, round(app.PointsEditField.Value));
                        ifbw_Hz   = app.RBWHzEditField.Value;
                        
                        disp("[START_SWEEP] about to configure VNA");
                        disp("fStart_Hz = " + string(fStart_Hz));
                        disp("fStop_Hz = " + string(fStop_Hz));
                        disp("nPts = " + string(nPts));
                        disp("ifbw_Hz = " + string(ifbw_Hz));

                        writeline(app.device, sprintf("SENS2:FREQ:STAR %g", fStart_Hz));
                        writeline(app.device, sprintf("SENS2:FREQ:STOP %g", fStop_Hz));
                        writeline(app.device, sprintf("SENS2:SWE:POIN %d", nPts));
                        if ~isnan(ifbw_Hz) && ifbw_Hz > 0
                            writeline(app.device, sprintf("SENS2:BAND %g", ifbw_Hz));
                        end
        
                        % single sweep trigger
                        writeline(app.device, "INIT2:CONT OFF");
                        writeline(app.device, "INIT2:IMM");   % start now
        
                        app.sweepStartTime = datetime("now");
                        disp("[START_SWEEP] switching to WAIT_SWEEP_DONE");
                        app.fsmState = "WAIT_SWEEP_DONE";
        
                    case "WAIT_SWEEP_DONE"
                        oldT = app.device.Timeout;
                        done = false;
        
                        % short "probe" timeout so UI stays responsive
                        app.device.Timeout = 1;
                        disp("[WAIT_SWEEP_DONE] querying *OPC?");
                        try
                            resp = strtrim(writeread(app.device, "*OPC?"));
                            done = strcmp(resp, "1");
                        catch
                            done = false;
                        end
        
                        % always restore timeout
                        app.device.Timeout = oldT;
        
                        if done
                            app.fsmState = "READ_SAVE";
                        end
                    case "READ_SAVE"
                        disp("[READ_SAVE] calling read_save_data()");
                        app.read_save_data();
                    
                        app.timeElapsed = seconds(datetime("now") - app.runStartTime);
                        if ~app.splitSweep && app.timeElapsed >= app.runDuration_s
                            app.stopRun();
                            disp("Runtime finished (saved final sweep).");
                            return;
                        end
                       
                    
                        if app.splitSweep
                            % advance to next frequency window
                            app.currentStartHz = app.currentStopHz;
                            app.currentStopHz  = min(app.currentStartHz + app.windowHz, app.overallStopHz);
                    
                            if app.currentStartHz >= app.overallStopHz
                                app.stopRun();
                                disp("Finished all sweep windows.");
                                return;
                            end
                        end
                    
                        app.fsmState = "START_SWEEP";
                    
        
                    otherwise
                        % IDLE / STOPPED: do nothing
                end
        
            catch ME
              
                disp("******** FSM ERROR ********");
                disp("State when error happened: " + app.fsmState);
                disp("Message:");
                disp(ME.message);
                disp("Extended report:");
                disp(getReport(ME, "extended"));
                app.stopRun();
            
            end
        end
        
        
        function read_save_data(app)
            app.SavingDataLamp.Color ='g'; 
            % Make timeout large for big ASCII transfers
            app.device.Timeout = 60;

        
            % (Optional but helpful) ensure ASCII data
            writeline(app.device,"FORM:DATA ASC");
            timestamp = datetime("now");
            % timestampStr = string(timestamp);
            % -------------------------------
            % Read Bluefors temperatures, resistance and reactance
            % -------------------------------
            try
                baseURL = "http://127.0.0.1:49099/values/";
            
                devLabels   = ["c1","c2","c5","c6"];
                chanNums    = [1, 2, 5, 6];
            
                T = nan(1, numel(chanNums));
                R = nan(1, numel(chanNums));
                X = nan(1, numel(chanNums));
            
                for ii = 1:numel(chanNums)
                    dev = devLabels(ii);
                    ch  = chanNums(ii);
            
                    % URLs
                    urlT = baseURL + "mapper.heater_mappings_bftc.device." + dev + "/temperature";
                    urlR = baseURL + "driver.bftc.data.channels.channel_" + ch + ".resistance";
                    urlX = baseURL + "driver.bftc.data.channels.channel_" + ch + ".reactance";
            
                    % Read JSON
                    rT = webread(urlT);
                    rR = webread(urlR);
                    rX = webread(urlX);
            
                    % Build MATLAB-safe field names
                    fieldT = "mapper_heater_mappings_bftc_device_" + dev + "_temperature";
                    fieldR = "driver_bftc_data_channels_channel_" + ch + "_resistance";
                    fieldX = "driver_bftc_data_channels_channel_" + ch + "_reactance";
            
                    % Extract numeric values
                    T(ii) = str2double(rT.data.(fieldT).content.latest_value.value);
                    R(ii) = str2double(rR.data.(fieldR).content.latest_value.value);
                    X(ii) = str2double(rX.data.(fieldX).content.latest_value.value);
                end
            
            catch ME
                disp("Bluefors read error:");
                disp(ME.message);
            
                T = nan(1,4);
                R = nan(1,4);
                X = nan(1,4);
                devLabels = ["c1","c2","c5","c6"];
                chanNums  = [1,2,5,6];
            end

            % try
            %     baseURL = "http://127.0.0.1:49099/values/";
            % 
            %     url_c1 = baseURL + "mapper.heater_mappings_bftc.device.c1/temperature";
            %     url_c2 = baseURL + "mapper.heater_mappings_bftc.device.c2/temperature";
            %     url_c5 = baseURL + "mapper.heater_mappings_bftc.device.c5/temperature";
            %     url_c6 = baseURL + "mapper.heater_mappings_bftc.device.c6/temperature";
            % 
            %     r1 = webread(url_c1);
            %     r2 = webread(url_c2);
            %     r5 = webread(url_c5);
            %     r6 = webread(url_c6);
            % 
            %     T_c1 = r1.data.mapper_heater_mappings_bftc_device_c1_temperature.content.latest_value.value
            %     % return
            %     T_c2 = r2.data.mapper_heater_mappings_bftc_device_c2_temperature.content.latest_value.value;
            %     T_c5 = r5.data.mapper_heater_mappings_bftc_device_c5_temperature.content.latest_value.value;
            %     T_c6 = r6.data.mapper_heater_mappings_bftc_device_c6_temperature.content.latest_value.value;
            % 
            % catch ME
            %     disp("Bluefors read error:");
            %     disp(ME.message);
            % 
            %     T_c1 = NaN;
            %     T_c2 = NaN;
            %     T_c5 = NaN;
            %     T_c6 = NaN;
            % end
            % Frequency axis (stimulus)
            ftxt = strtrim(writeread(app.device,"CALC2:DATA:STIM?"));   % stimulus values :contentReference[oaicite:2]{index=2}
            fHz = sscanf(strrep(ftxt,',',' '), '%f');
        
            % Complex trace data (Re/Im interleaved)
            dtxt = strtrim(writeread(app.device,"CALC2:DATA? SDAT"));   % Re/Im pairs :contentReference[oaicite:3]{index=3}
            y = sscanf(strrep(dtxt,',',' '), '%f');
        
            re = y(1:2:end);
            im = y(2:2:end);
            s  = complex(re, im);
        
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            fHz = fHz(1:n);
            s   = s(1:n);
            % disp(fHz);
            % disp(s);
            disp("done read");
            % Match lengths (just in case)
            n = min(numel(fHz), numel(s));
            % return
            fHz = fHz(1:n);
            s   = s(1:n);
            
            
            % Convert to useful quantities
            mag_dB = 20*log10(abs(s));
            phase_deg = angle(s)*180/pi;
            real_part = real(s);
            imag_part = imag(s);
            
            % Create table

            % T = table( ...
            %    repmat(timestamp, n, 1), ...
            %    fHz, real_part, imag_part, mag_dB, phase_deg, ...
            %    'VariableNames', {'Timestamp','Frequency_Hz','Real','Imag','Magnitude_dB','Phase_deg'});
            % T = table( ...
            %     repmat(timestamp, n, 1), ...
            %     fHz, ...
            %     real_part, ...
            %     imag_part, ...
            %     mag_dB, ...
            %     phase_deg, ...
            %     repmat(T_c1, n, 1), ...
            %     repmat(T_c2, n, 1), ...
            %     repmat(T_c5, n, 1), ...
            %     repmat(T_c6, n, 1), ...
            %     'VariableNames', { ...
            %     'Timestamp', ...
            %     'Frequency_Hz', ...
            %     'Real', ...
            %     'Imag', ...
            %     'Magnitude_dB', ...
            %     'Phase_deg', ...
            %     'Temp_C1_K', ...
            %     'Temp_C2_K', ...
            %     'Temp_C5_K', ...
            %     'Temp_C6_K'});
            % % Create filename with timestamp
            % filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";
            % 
            % % Save file
            % writetable(T, filename);
            % 
            % disp("Saved file:");
            % disp(filename);

            % Create filename with timestamp
            % filename = "../VNA_data/ZNB_trace_" + datestr(now,'yyyymmdd_HHMMSS') + ".csv";
            % Create filename with timestamp + sweep window info
            filename = sprintf("../VNA_data/ZNB_trace_%s_sweep%04d_%.0fHz_to_%.0fHz.csv", ...
                datestr(now,'yyyymmdd_HHMMSS_FFF'), ...
                app.sweepIdx, ...
                app.currentStartHz, ...
                app.currentStopHz);
            
            fid = fopen(filename, 'w');
            if fid == -1
                error("Could not open file for writing: %s", filename);
            end
            
            % Write metadata header
            fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            fprintf(fid, 'Sweep Index: %d\n', app.sweepIdx);
            fprintf(fid, 'Sweep Start Hz: %.12g\n', app.currentStartHz);
            fprintf(fid, 'Sweep Stop Hz: %.12g\n', app.currentStopHz);
            
            if app.splitSweep
                fprintf(fid, 'Split Sweep: true\n');
                fprintf(fid, 'Overall Start Hz: %.12g\n', app.overallStartHz);
                fprintf(fid, 'Overall Stop Hz: %.12g\n', app.overallStopHz);
                fprintf(fid, 'Window Hz: %.12g\n', app.windowHz);
            else
                fprintf(fid, 'Split Sweep: false\n');
            end
            
            fprintf(fid, '\nBluefors Channel Data:\n\n');
            
            for ii = 1:numel(chanNums)
                fprintf(fid, 'C%d Temperature_K: %.12g\n', chanNums(ii), T(ii));
                fprintf(fid, 'C%d Resistance_Ohm: %.12g\n', chanNums(ii), R(ii));
                fprintf(fid, 'C%d Reactance_Ohm: %.12g\n', chanNums(ii), X(ii));
                fprintf(fid, '\n');
            end
            
            fprintf(fid, 'Frequency_Hz,Real,Imag,Magnitude_dB,Phase_deg\n');
            
            for k = 1:n
                fprintf(fid, '%.12e,%.12e,%.12e,%.6f,%.6f\n', ...
                    fHz(k), real_part(k), imag_part(k), mag_dB(k), phase_deg(k));
            end
            
            fclose(fid);
            
            disp("Saved file with header:");
            disp(filename);
            app.SavingDataLamp.Color = 'r';
            % filename = sprintf("../VNA_data/ZNB_trace_%s_sweep%04d_%.0fHz_to_%.0fHz.csv", ...
            %     datestr(now,'yyyymmdd_HHMMSS_FFF'), ...
            %     app.sweepIdx, ...
            %     app.currentStartHz, ...
            %     app.currentStopHz);
            % 
            % % Open file manually
            % fid = fopen(filename, 'w');
            % disp("Saving file:");
            % disp(filename);
            % 
            % % return
            % % Write metadata header
            % % fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            % % fprintf(fid, 'Bluefors Channel Data:\n\n');
            % 
            % fprintf(fid, 'Measurement Timestamp: %s\n', string(timestamp));
            % fprintf(fid, 'Sweep Index: %d\n', app.sweepIdx);
            % fprintf(fid, 'Sweep Start Hz: %.12g\n', app.currentStartHz);
            % fprintf(fid, 'Sweep Stop Hz: %.12g\n', app.currentStopHz);
            % 
            % if app.splitSweep
            %     fprintf(fid, 'Split Sweep: true\n');
            %     fprintf(fid, 'Overall Start Hz: %.12g\n', app.overallStartHz);
            %     fprintf(fid, 'Overall Stop Hz: %.12g\n', app.overallStopHz);
            %     fprintf(fid, 'Window Hz: %.12g\n', app.windowHz);
            % else
            %     fprintf(fid, 'Split Sweep: false\n');
            % end
            % 
            % fprintf(fid, '\nBluefors Channel Data:\n\n');
            % 
            % for ii = 1:numel(chanNums)
            %     fprintf(fid, 'C%d Temperature_K: %.12g\n', chanNums(ii), T(ii));
            %     fprintf(fid, 'C%d Resistance_Ohm: %.12g\n', chanNums(ii), R(ii));
            %     fprintf(fid, 'C%d Reactance_Ohm: %.12g\n', chanNums(ii), X(ii));
            %     fprintf(fid, '\n');
            % end
            % 
            % % Write column header
            % fprintf(fid, 'Frequency_Hz,Real,Imag,Magnitude_dB,Phase_deg\n');
            % 
            % % Write numeric data
            % for k = 1:n
            %     fprintf(fid, '%.12e,%.12e,%.12e,%.6f,%.6f\n', ...
            %         fHz(k), real_part(k), imag_part(k), mag_dB(k), phase_deg(k));
            % end
            % 
            % fclose(fid);
            % 
            % disp("Saved file with header:");
            % disp(filename);
            % app.SavingDataLamp.Color = 'r';

            % read final file size 
            fileSize = dir(filename).bytes/1e6;

            % add stored data to plot
            last = 0;
            if(length(app.fileSizeY) > 0)
                last = app.fileSizeY(end);
            end
            app.fileSizeX = [app.fileSizeX app.timeElapsed/3600]
            app.fileSizeY = [app.fileSizeY (fileSize+last)]

            app.fileSizeLine = plot(app.dataCollectAxes,app.fileSizeX,app.fileSizeY,LineWidth=2,Color='#ff4500 ');

            % Plot in app axes
            app.sParamLine = plot(app.sParamAxes,fHz,mag_dB);

            % update UI list
            app.CurrentFilenameEditField.Value = filename;
            items = string(app.CollectedFilesListBox.Items);
            if numel(items)==1 && strlength(items(1))==0
                items = strings(0,1);
            end
            items(end+1) = filename;
            app.CollectedFilesListBox.Items = cellstr(items);
            app.CollectedFilesListBox.Value = char(filename);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Start');
            % start timer

            % update warning
            selection = uiconfirm(app.UIFigure, ...
            'Check for Windows auto-update BEFORE starting collection!', ...
            'Warning', ...
            'Options', {'OK, I checked'}, ...
            'Icon', 'warning');

            % uialert(app.UIFigure, 'Check for Windows auto-update BEFORE starting collection!!', ...
            % 'WARNING Message','Icon','warning',);

            app.timeRemaining = 3600*app.RuntimehrEditField.Value;
            app.timeElapsed = 0;

            disp(app.timeRemaining);
            app.ConnectButton.Enable = "on";

            app.fileSizeX = [];
            app.fileSizeY = [];

            % check 'VNA_DATA' folder exists, if not, create it
            files = dir;
            files= dir('..');
            found=0;
            for f=1:length(files)
                file = files(f).name;
                if(contains(file,'VNA_DATA')&&~contains(file,'ARCHIVE'))
                    found=1;
                    break;
                end
            end
            if(~found)
                mkdir ../VNA_DATA
            end


            %app.appTimer = timer( ...
            %    'ExecutionMode', 'fixedRate', ...
            %    'Period', 1, ...                 % seconds
            %    'TimerFcn', @(~,~) countdownCallback(app));
        
            %start(app.appTimer);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.device)
                disp("Not connected.");
                return;
            end
        
            app.overallStartHz = app.SweepStartMHzEditField.Value * 1e6;
            app.overallStopHz  = app.SweepStopMHzEditField.Value  * 1e6;
            app.splitSweep     = app.SplitSweepCheckBox.Value;
        
            if app.overallStopHz <= app.overallStartHz
                uialert(app.UIFigure, 'Sweep stop must be greater than sweep start.', 'Invalid Sweep Range');
                return;
            end
        
            if app.splitSweep
                app.windowHz = app.WindowMHzEditField.Value * 1e6;
        
                if app.windowHz <= 0
                    uialert(app.UIFigure, 'Window must be positive.', 'Invalid Window');
                    return;
                end
        
                app.currentStartHz = app.overallStartHz;
                app.currentStopHz  = min(app.currentStartHz + app.windowHz, app.overallStopHz);
                app.runDuration_s = inf;
            else
                app.windowHz = NaN;
                app.currentStartHz = app.overallStartHz;
                app.currentStopHz  = app.overallStopHz;
                app.runDuration_s = 3600 * app.RuntimehrEditField.Value;
            end
        
            app.runStartTime = datetime("now");
            app.sweepIdx = 0;
            app.ConnectButton.Enable = 'off';
        
            app.fsmState = "START_SWEEP";
            app.StateEditField.Value = char(app.fsmState);
            app.isStopped = false;
        
            if ~isempty(app.fsmTimer) && isvalid(app.fsmTimer)
                stop(app.fsmTimer);
                delete(app.fsmTimer);
            end
        
            app.fsmTimer = timer( ...
                "ExecutionMode","fixedSpacing", ...
                "Period", 0.25, ...
                "TimerFcn", @(~,~) app.fsmTick() );
        
            start(app.fsmTimer);
            app.StopButton.Enable = 'on';
      
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.device = visadev("USB0::0x0AAD::0x01A0::102106::INSTR");
                configureTerminator(app.device,"LF");
                app.device.Timeout = 10;
        
                idn = strtrim(writeread(app.device,"*IDN?"));
                disp(idn);
        
                app.ConnectionStatusLamp.Color = [0 1 0];
                app.StartButton.Enable = 'on';
            catch ME
                disp(ME.message);
                app.ConnectionStatusLamp.Color = [1 0 0];
            end 
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopRun();

        end

        % Value changed function: RuntimehrEditField
        function RuntimehrEditFieldValueChanged(app, event)
            value = app.RuntimehrEditField.Value;
            app.timeRemaining = value*3600;
        end

        % Value changed function: PowerdBmEditField
        function PowerdBmEditFieldValueChanged(app, event)
            value = app.PowerdBmEditField.Value;
            % TODO
        end

        % Value changed function: SplitSweepCheckBox
        function SplitSweepCheckBoxValueChanged(app, event)
            isOn = app.SplitSweepCheckBox.Value;
        
            if isOn
                app.WindowMHzEditField.Visible = 'on';
                app.WindowMHzLabel.Visible = 'on';
                app.WindowMHzEditField.Editable = 'on';
        
                % disable runtime controls in split-sweep mode
                app.RuntimehrEditField.Enable = 'off';
                app.RuntimehrEditFieldLabel.Enable = 'off';
            else
                app.WindowMHzEditField.Visible = 'off';
                app.WindowMHzLabel.Visible = 'off';
        
                % enable runtime controls in normal mode
                app.RuntimehrEditField.Enable = 'on';
                app.RuntimehrEditFieldLabel.Enable = 'on';
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SweepStartMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStartMHzEditFieldLabel.Text = 'Sweep Start (MHz)';
            app.SweepStartMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStartMHzEditField.Value = 60;
            app.SweepStopMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SweepStopMHzEditFieldLabel.Text = 'Sweep Stop (MHz)';
            app.SweepStopMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SweepStopMHzEditField.Value = 67;
            app.RBWHzEditFieldLabel = uilabel(app.LeftPanel);
            app.RBWHzEditFieldLabel.Text = 'RBW (Hz)';
            app.RBWHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RBWHzEditField.Value = 200;
            app.PointsEditFieldLabel = uilabel(app.LeftPanel);
            app.PointsEditFieldLabel.Text = '# Points ';
            app.PointsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointsEditField.Value = 100000;
            app.MessageTextAreaLabel = uilabel(app.LeftPanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.LeftPanel);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.RuntimehrEditFieldLabel = uilabel(app.LeftPanel);
            app.RuntimehrEditFieldLabel.Text = 'Runtime (hr)';
            app.RuntimehrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RuntimehrEditField.ValueChangedFcn = createCallbackFcn(app, @RuntimehrEditFieldValueChanged, true);
            app.RuntimehrEditField.Value = 22;
            app.PowerdBmEditFieldLabel = uilabel(app.LeftPanel);
            app.PowerdBmEditFieldLabel.Enable = 'off';
            app.PowerdBmEditFieldLabel.Text = 'Power (dBm)';
            app.PowerdBmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PowerdBmEditField.ValueChangedFcn = createCallbackFcn(app, @PowerdBmEditFieldValueChanged, true);
            app.PowerdBmEditField.Editable = 'off';
            app.PowerdBmEditField.Enable = 'off';
            app.PowerdBmEditField.Value = -10;
            app.SplitSweepCheckBox = uicheckbox(app.LeftPanel);
            app.SplitSweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitSweepCheckBoxValueChanged, true);
            app.SplitSweepCheckBox.Text = 'Split Sweep';
            app.WindowMHzLabel = uilabel(app.LeftPanel);
            app.WindowMHzLabel.Visible = 'off';
            app.WindowMHzLabel.Text = 'Window (MHz)';
            app.WindowMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WindowMHzEditField.Visible = 'off';
            app.WindowMHzEditField.Value = 7;
            app.RightPanel = uipanel(app.UIFigure);
            app.dataCollectAxes = uiaxes(app.RightPanel);
            title(app.dataCollectAxes, 'Total Data Collected')
            xlabel(app.dataCollectAxes, 'Time (hr)')
            ylabel(app.dataCollectAxes, 'Data (MB)')
            zlabel(app.dataCollectAxes, 'Z')
            app.sParamAxes = uiaxes(app.RightPanel);
            title(app.sParamAxes, 'S21')
            xlabel(app.sParamAxes, 'Frequency (MHz)')
            ylabel(app.sParamAxes, 'dB(|S21|)')
            zlabel(app.sParamAxes, 'Z')
            app.StateEditFieldLabel = uilabel(app.RightPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.RightPanel, 'text');
            app.ProgressLabel = uilabel(app.RightPanel);
            app.ProgressLabel.Text = 'Progress:';
            app.percentLabel = uilabel(app.RightPanel);
            app.percentLabel.Text = '0%';
            app.progress_BG = uilabel(app.RightPanel);
            app.progress_BG.Text = '';
            app.progress_FG = uilabel(app.RightPanel);
            app.progress_FG.Text = '';
            app.SweepingLampLabel = uilabel(app.RightPanel);
            app.SweepingLampLabel.Text = 'Sweeping';
            app.SweepingLamp = uilamp(app.RightPanel);
            app.SavingDataLampLabel = uilabel(app.RightPanel);
            app.SavingDataLampLabel.Text = 'Saving Data';
            app.SavingDataLamp = uilamp(app.RightPanel);
            app.CurrentFilenameEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentFilenameEditFieldLabel.Text = 'Current Filename';
            app.CurrentFilenameEditField = uieditfield(app.RightPanel, 'text');
            app.CollectedFilesListBoxLabel = uilabel(app.RightPanel);
            app.CollectedFilesListBoxLabel.Text = 'Collected Files';
            app.CollectedFilesListBox = uilistbox(app.RightPanel);
            app.CollectedFilesListBox.Items = {''};
            app.CollectedFilesListBox.Value = '';
            app.ElapsedEditFieldLabel = uilabel(app.RightPanel);
            app.ElapsedEditFieldLabel.Text = 'Elapsed';
            app.ElapsedEditField = uieditfield(app.RightPanel, 'text');
            app.RemainingEditFieldLabel = uilabel(app.RightPanel);
            app.RemainingEditFieldLabel.Text = 'Remaining';
            app.RemainingEditField = uieditfield(app.RightPanel, 'text');
            app.ConnectionStatusLampLabel = uilabel(app.RightPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VNA_control_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end